classdef App_plotDTF_4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Terminal                   matlab.ui.Figure
        SavedataButton             matlab.ui.control.Button
        AD5940DFTPlotLabel         matlab.ui.control.Label
        SerialConnectionPanel      matlab.ui.container.Panel
        ClearButton                matlab.ui.control.Button
        ConnectButton              matlab.ui.control.Button
        BaudrateDropDown           matlab.ui.control.DropDown
        BaudrateDropDownLabel      matlab.ui.control.Label
        PortDropDown               matlab.ui.control.DropDown
        PortLabel                  matlab.ui.control.Label
        TerminalPanel              matlab.ui.container.Panel
        ReceiveddataTextArea       matlab.ui.control.TextArea
        ReceiveddataTextAreaLabel  matlab.ui.control.Label
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

   properties (Access = private)
        %timers
        generic_upd_tmr = timer;
        %data
        port_list;
        rsvd_log;
        %vars
        current_port = '';
        %sem
        port_fxn_pass = false;
        con_in_process = false;
        %plot
        time    %Sample vector
        mag     %Magnitude vector
        phase   %Phase vector
        sample_period 
        data
        data_mag
        data_phase
        temp
        marker %Marker lable vector
        count
        peek_saved
        time_saved
    end 
   
% Versione 2.1 DTF Data Plot   
        
    
    methods (Access = private)
        
        %%% GUI Update Function
        function  form_upd(app)
            switch app.con_state("left",false)
                case 'Connected'
                    %% This time needn't to update gui
                    
%                      if(app.current_port.BytesAvailable)
%                        app.rsvd_log{end+1} = fscanf(app.current_port); 
%                        app.rsvd_log{end} = app.rsvd_log{end}(1:end-1);
%                        app.ReceiveddataTextArea.Value = app.rsvd_log;
%                      end
                case 'Disconnected'
                   %portlist 
                   s_info = instrhwinfo('serial');
                   app.port_list =  s_info.AvailableSerialPorts;
                   %gui
                   app.PortDropDown.Items = app.port_list;
                   app.PortDropDown.ItemsData = app.port_list;
            end
        end
        
        
    %%% Connection Function        
    function connect_fxn(app)
           %% port connection module
            try 
                app.con_in_process=true;
                app.current_port = serial(app.PortDropDown.Value);
                fopen(app.current_port);
                app.port_fxn_pass = true;
            catch ME
                if  (ME.identifier == "MATLAB:serial:fopen:opfailed")
                    warning('MATLAB_serial_fopen_opfailed');                   
                else
                     warning('Connection_failen_unknown_reason');
                end 
                app.port_fxn_pass=false;
            end
                app.con_in_process = false;
                
            %% end of port connection module
            
            % Serial settings, GUI update and variables initialize
            if (app.con_state("left",false) == "Connected")
                set(app.current_port,'BaudRate',app.BaudrateDropDown.Value);
                set(app.current_port,'FlowControl','Software');
                app.current_port.BytesAvailableFcn = {@app.save_data};
                app.ConnectButton.Text = 'Disconnect';
                app.PortDropDown.Enable = 'off';
                app.BaudrateDropDown.Enable = 'off';
                app.time = [];
                app.mag = [];
                app.marker = [];
                app.count =0;
                
            %   app.xtime=zeros(1,50);
                app.sample_period = 1./5000;  % in this case Freq is fixed - teke frq from the board
            end
        end

        %%% Save Data Function - read from serial port and update Terminal
        %%% and plot the data
        function save_data(app,src,event)
            app.temp = fscanf(app.current_port);
            
            % convert received string in double
            app.data_mag=str2double(char(app.temp(1:10)));
            app.data_phase=str2double(char(app.temp(12:end))); 
            
            % Print recived string in Received Text Area
            app.data{end+1}=app.temp;
            app.data{end} = app.data{end}(1:end-1);
            app.ReceiveddataTextArea.Value = app.data;
            scroll(app.ReceiveddataTextArea, 'bottom'); % autoscroll Text Area
            
            % Update vectors with new value
            if isempty(app.time)
                app.time = [app.time 0];
            else
                app.time = [app.time (app.time(end) + app.sample_period)];
            end
            app.mag=[app.mag app.data_mag];
            app.phase=[app.phase app.data_phase];
            
            % Set autoadjust axes based on plotted value
            % Only for the first 50 sample the X starts from 0
            if(app.time(end)+app.sample_period<(50*app.sample_period))
                t1=0;
            else
                t1= app.time(end)-(50*app.sample_period);
            end
            t2= app.time(end)+app.sample_period;
            m1= app.data_mag-4;
            m2= app.data_mag+4;
%           p1= app.data_phase-4;
%           p2= app.data_phase+4;

            % Set_Marker fnc
            if(app.data_mag > (1.2*(mean(app.mag))))
                app.marker = [app.marker "Peak"];
                app.count = 50*app.sample_period;
                app.peek_saved=app.data_mag;
                app.time_saved=app.time(end);
            else
                app.marker = [app.marker " "];
            end

            if app.count>0
                m2=app.peek_saved+4;
                app.count=app.count-app.sample_period;
            end
            
            % Data Plot
            if length(app.time) > 1
                plot(app.UIAxes, app.time, app.mag,'k');
                axis(app.UIAxes,[t1 t2 m1 m2]);  % dinamic adjust axis base on data 
                hold(app.UIAxes,'on');
                % plot marker 
                if(app.count>0)
                    plot(app.UIAxes,app.time_saved,app.peek_saved,'-o', 'MarkerFaceColor','r');  %set marker 
                    text(app.UIAxes,app.time_saved,app.peek_saved,'      Peak');
                  
              
                end
                
                plot(app.UIAxes_2, app.time, app.phase, 'r');
%               axis(app.UIAxes_2,[t1 t2 p1 p2]);
            end
            
        end
        
        
        %%% Disconnect Function
        function disconnect_fxn(app)
            if  app.con_state("left",false) == "Connected"
                app.ConnectButton.Text = 'connect';
                app.con_state("set",false);
                try
                    fclose(app.current_port);
                catch 
                    warning('Disconnect_failed');
                end
                app.current_port = '';
                app.PortDropDown.Enable = 'on';
                app.BaudrateDropDown.Enable = 'on';
%               stop(app.generic_upd_tmr);
            end
        end
        
        %%% Save to file Function
        function save_file(app)
            try
                ok={'File salvato correttamente','FileName -> Salvataggio.txt'};
                
                % Transpose of Data vectors
                Magnitude=transpose(app.mag);
                Phase=transpose(app.phase);
                Sample=transpose(app.time);
                Marker=transpose(app.marker);
                
                %Create Table
                data_file=table(Sample,Magnitude,Phase,Marker);
                
                %Write table on specified file
                writetable(data_file,'C:\Users\papuz\OneDrive\Desktop\IMSI_Project\Salvataggio.txt','Delimiter', 'tab', 'WriteMode','Append');
                
                %Pop up alert
                uialert(app.Terminal,ok,'Success','Icon','success');
                
            catch
                %Pop up alert
                No={'Errore nel salvataggio'};
                uialert(app.Terminal,No,'Error');
            end   
        end
        
        
        %%% State Function
        function state = con_state(app,set,val)
            state = "Disconnected";
            if (app.port_fxn_pass && ~app.con_in_process)
                state = "Connected";
            end
            if (app.port_fxn_pass && app.con_in_process)
                state = "Connecting";
            end
            if (set == "set")
                app.port_fxn_pass = val;
                app.con_in_process = val;
            end
      end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Create gui upd tmer but unused in this case
            
%            app.generic_upd_tmr.Period = 0.1;
%            app.generic_upd_tmr.ExecutionMode = 'fixedRate';
%            app.generic_upd_tmr.TimerFcn = @(~, ~)app.form_upd;
           
           % Baudrate drop-down
           app.BaudrateDropDown.Items = {'9600','38400','115200','230400'};
           app.BaudrateDropDown.ItemsData = {9600, 38400, 115200, 230400};
           
           % Update form
           app.form_upd();
           
%          start(app.generic_upd_tmr);
        end

        % Close request function: Terminal
        function TerminalCloseRequest(app, event)
            app.disconnect_fxn();
            delete(app)
            
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            %when button is pushed checks fisr if COM is set
            if(~isempty(app.PortDropDown.Value))
                switch app.con_state("left",false)
                    case "Connected"
                        app.disconnect_fxn();
                    case "Disconnected"
                     app.connect_fxn();
                end
            end
        end

        % Callback function: ClearButton, UIAxes
        function ClearButtonPushed(app, event)
            % Disconnect and clear plots and terminal
            app.disconnect_fxn();
            delete(instrfind);
            app.ReceiveddataTextArea.Value=' ';
            cla(app.UIAxes);
            cla(app.UIAxes_2);
        end

        % Button pushed function: SavedataButton
        function SavedataButtonPushed(app, event)
                 switch app.con_state("left",false)
                    case "Connected"
                        % nothing
                    case "Disconnected"
                        
                        %call sava file function
                        app.save_file();
                end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Terminal = uifigure('Visible', 'off');
            app.Terminal.Name = 'UI Figure';
            app.Terminal.CloseRequestFcn = createCallbackFcn(app, @TerminalCloseRequest, true);
            app.UIAxes = uiaxes(app.Terminal);
            title(app.UIAxes, 'Magnitude')
            xlabel(app.UIAxes, 'Sample')
            ylabel(app.UIAxes, 'Value')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.UIAxes_2 = uiaxes(app.Terminal);
            title(app.UIAxes_2, 'Phase')
            xlabel(app.UIAxes_2, 'Sample')
            ylabel(app.UIAxes_2, 'Value')
            zlabel(app.UIAxes_2, 'Z')
            app.TerminalPanel = uipanel(app.Terminal);
            app.TerminalPanel.Title = 'Terminal';
            app.ReceiveddataTextAreaLabel = uilabel(app.TerminalPanel);
            app.ReceiveddataTextAreaLabel.Text = 'Received data';
            app.ReceiveddataTextArea = uitextarea(app.TerminalPanel);
            app.SerialConnectionPanel = uipanel(app.Terminal);
            app.SerialConnectionPanel.Title = 'Serial Connection';
            app.PortLabel = uilabel(app.SerialConnectionPanel);
            app.PortLabel.Text = 'Port';
            app.PortDropDown = uidropdown(app.SerialConnectionPanel);
            app.PortDropDown.Items = {};
            app.PortDropDown.Value = {};
            app.BaudrateDropDownLabel = uilabel(app.SerialConnectionPanel);
            app.BaudrateDropDownLabel.Text = 'Baud rate';
            app.BaudrateDropDown = uidropdown(app.SerialConnectionPanel);
            app.BaudrateDropDown.Items = {'', 'Option 2', 'Option 3', 'Option 4'};
            app.BaudrateDropDown.Value = '';
            app.ConnectButton = uibutton(app.SerialConnectionPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.ClearButton = uibutton(app.SerialConnectionPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.AD5940DFTPlotLabel = uilabel(app.Terminal);
            app.AD5940DFTPlotLabel.Text = 'AD5940 DFT Plot ';
            app.SavedataButton = uibutton(app.Terminal, 'push');
            app.SavedataButton.ButtonPushedFcn = createCallbackFcn(app, @SavedataButtonPushed, true);
            app.SavedataButton.Text = 'Save data';
            % Show the figure after all components are created
            app.Terminal.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_plotDTF_4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Terminal)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Terminal)
        end
    end
end